classdef ArduinoControllerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ArduinoControllerPanel   matlab.ui.container.Panel
        ResetArduinoButton       matlab.ui.control.Button
        TestIntervalEditField    matlab.ui.control.EditField
        SetTestIntervalmmButton  matlab.ui.control.Button
    end

    
    methods (Access = public)
        function ret_mm = return_to_start(comp)
            ardiuno_serial = app.arduino_serial;
            MOVE_TO_START = 2;
            serial_string = strcat(num2str(MOVE_TO_START),",0,1");
            ret_mm = serial_communication(comp, ardiuno_serial, serial_string);
            flush(s);
        end 
        function sig = serial_communication(comp, s, message)
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
        end

        function custom_write(comp, s, message)
            flush(s);
            writeline(s, message);
        end
        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetArduinoButton
        function ResetArduinoButtonPushed(comp, event)
            reset_arduino(comp);
        end

        % Button pushed function: SetTestIntervalmmButton
        function SetTestIntervalmmButtonPushed(comp, event)
            test_interval = app.test_interval; 
            try
            test_interval_input = str2double(comp.TestIntervalEditField.Value);
            if test_interval_input <= 0 || test_interval > 76
               str = strcat("Please input test interval lager than 0mm and less than 76mm 100 and 2000. Inputed: ", num2str(test_interval_input));
               fig = uifigure;
               uialert(fig,str,'Inclinometer Controller Error');
               return;
            end 
            test_interval = roof(test_interval_input);
            catch e
                fig = uifigure;
                uialert(fig,e,'Arduino Controller Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 292 132];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ArduinoControllerPanel = uipanel(comp);
            comp.ArduinoControllerPanel.Title = 'Arduino Controller';
            comp.SetTestIntervalmmButton = uibutton(comp.ArduinoControllerPanel, 'push');
            comp.SetTestIntervalmmButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetTestIntervalmmButtonPushed, true);
            comp.SetTestIntervalmmButton.Text = 'Set Test Interval (mm)';
            comp.TestIntervalEditField = uieditfield(comp.ArduinoControllerPanel, 'text');
            comp.TestIntervalEditField.Placeholder = 'Enter Test Interval';
            comp.ResetArduinoButton = uibutton(comp.ArduinoControllerPanel, 'push');
            comp.ResetArduinoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetArduinoButtonPushed, true);
            comp.ResetArduinoButton.Text = 'Reset Arduino';
        end
    end
end