classdef ForceGaugeComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ForceGaugeStepperMotorsControllerPanel  matlab.ui.container.Panel
        DirectionSwitch                 matlab.ui.control.Switch
        DesiredAngledegreesEditField    matlab.ui.control.EditField
        DesiredAngledegreesEditFieldLabel  matlab.ui.control.Label
        StepSizemmLevelEditField        matlab.ui.control.EditField
        StepSizemmEditField_2Label      matlab.ui.control.Label
        PrecisiondegreesEditField       matlab.ui.control.EditField
        PrecisiondegreesEditFieldLabel  matlab.ui.control.Label
        StepSizemmForceEditField        matlab.ui.control.EditField
        StepSizemmEditFieldLabel        matlab.ui.control.Label
        MoveForceGaugeSteppertoDesiredForceButton  matlab.ui.control.Button
        MovetoDesiredForcelbsEditField  matlab.ui.control.EditField
        MovetoDesiredForcelbsEditFieldLabel  matlab.ui.control.Label
        LevelForceGaugeStepperMotorsButton  matlab.ui.control.Button
        MoveForceGaugeStepperMotorsXmmButton  matlab.ui.control.Button
        MoveRightStepperMotormmEditField  matlab.ui.control.EditField
        MoveRightStepperMotormmEditFieldLabel  matlab.ui.control.Label
        MoveLeftStepperMotormmEditField  matlab.ui.control.EditField
        MoveLeftStepperMotormmEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)

        function ret_signal = move_force_gauges(comp, left_mm, right_mm)
            MOVE_FORCE_GAUGES = 24;
            % global app.ardiuno_serial;
            arduino_serial = evalin('base', 'arduino_serial');
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(left_mm),",",num2str(right_mm));
                ret_signal = serial_communication(comp, arduino_serial, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true);  
                end
                flush(arduino_serial);
            end
        end

        % reads force data from sensor (written by fin)
        function [force1,force2] = force_data(comp,serial1,serial2)
            %connects and reads first sensor
            %serial1=serialport(portname1,9600); %connects to serial port 
            write(serial1,'?','string');        %queries serial port (required to obtain data)
            force1=read(serial1,5,"string");    %reads data point
        
            %connects and read second sensor
            %serial2=serialport(portname2,9600); %connects to serial port
            write(serial2,'?','string');        %queries serial port (required to obtain data)
            force2=read(serial2,5,"string");    %reads data point
        
            %converts and outputs data for first sensor
            force1=strrep(force1,' ','');       %removes any spaces in read data
            force1=strrep(force1,'l','1');      %changes L to 1 (weird data read)
            force1=str2double(force1);          %converts data to double
        
            %converts and outputs data for second sensor
            force2=strrep(force2,' ','');       %removes any spaces in read data
            force2=strrep(force2,'l','1');      %changes L to 1 (weird data read)
            force2=str2double(force2);          %converts data to double
            flush(serial1);
            flush(serial2);
        end
        
        % gets the average force data (written by fin)
        function [force1,force2] = force_average(comp, ser1, ser2,average)  
            for i=1:average
                [force1(i),force2(i)]=force_data(comp, ser1,ser2);
            end
            force1=mean(force1);
            force2=mean(force2);
        end

        function ret_signal = move_force_gauges_until_desired_force(comp, desired_force, step_size, direction)
            arduino_serial = evalin('base', 'arduino_serial');
            force_gauge_left_serial = evalin('base', 'force_gauge_left_serial');
            force_gauge_right_serial = evalin('base', 'force_gauge_right_serial');
            MOVE_FORCE_GAUGES = 24;
            [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
            precision = 0.2;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            ret_signal = 24;
            if direction == "Up"%force_right < (desired_force+precision) && force_left < (desired_force + precision)
                while (force_left < (desired_force-precision) || force_right < (desired_force-precision)) && ~(str2double(ret_signal) == 86)
                    serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(step_size),",",num2str(step_size));
                    ret_signal = serial_communication(comp, arduino_serial, serial_string);
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
            elseif direction  == "Down"
                if force_right < 20 || force_left < 20
                    step_size = 10;
                end
                if force_right < 10 || force_left < 10
                    step_size = 5;
                end
                if force_right < 5 || force_left < 5
                    step_size = 2;
                end
                while (force_left > (desired_force+precision) || force_right > (desired_force+precision)) && ~(str2double(ret_signal) == 86)
                    serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(-1*step_size),",",num2str(-1*step_size));
                    ret_signal = serial_communication(comp, arduino_serial, serial_string);
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    if force_right < 20 || force_left < 20
                        step_size = 10;
                    end
                    if force_right < 10 || force_left < 10
                        step_size = 5;
                    end
                    if force_right < 5 || force_left < 5
                        step_size = 2;
                    end
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
            end
            
            flush(arduino_serial);
        end
        
        function ret_signal = level_force_gauges(comp, desired_angle, precision, step_size)
            
            level_inclinometer_serial = evalin("base","level_inclinometer_serial");
            arduino_serial  = evalin("base", "arduino_serial");
            desired_angle = desired_angle + 90;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            MOVE_FORCE_GAUGES = 24;
            % global app.ardiuno_serial level_inclinometer_serial;
            [roll, pitch] = get_HWT905TTL_data(comp, level_inclinometer_serial);
            assignin("base", "current_pitch", pitch);
            assignin("base", "current_roll", roll);
            disp(pitch);
            disp(roll);
            ret_signal = 24;
                if abs(pitch - desired_angle) < 10
                    step_size = 7;
                end
                if abs(pitch - desired_angle) < 5
                    step_size = 5;
                end
                if abs(pitch - desired_angle) < 1
                    step_size = 1;
                end
            while ~(roll < (desired_angle + precision) && roll > (desired_angle - precision)) && ~(str2double(ret_signal) == 86)
                % step_size = gradient descent step size adjustment(roll, desired_angle, step_size)
                message = make_message(comp, int2str(MOVE_FORCE_GAUGES),roll, desired_angle, precision, step_size);
                ret_signal = serial_communication(comp, arduino_serial, message);
                [roll, pitch] = get_inclinometer_reading(comp, level_inclinometer_serial);
                disp(pitch);
                disp(roll);
                if abs(pitch - desired_angle) < 10
                    step_size = 7;
                end
                if abs(pitch - desired_angle) < 5
                    step_size = 5;
                end
                if abs(pitch - desired_angle) < 1
                    step_size = 1;
                end
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
            end
            flush(arduino_serial);
        end
        
        function message = make_message(comp, m, actual_angle, desired_angle, precision, step_size)
            message = m;
            if actual_angle <= desired_angle+precision && actual_angle >= desired_angle-precision % make this a fuction that takes in actual and desired, concatenates and retruns message
                  message = strcat(message,',0',',0');
            elseif actual_angle < desired_angle
                  message = strcat(message,',0',',',num2str(step_size)); 
            else
                  message = strcat(message,',',num2str(step_size),',0'); 
            end 
        end

        function [pitch, roll] = get_HWT905TTL_data(comp, port)
            f = 20;%DataFrequce
            aa=[0 0 0];
            ww=[0 0 0];
            AA = [0 0 0];
            t=0;
            tt = 0;
            a=[0 0 0]';
            w=[0 0 0]';
            A=[0 0 0]';
            while(1)
                Head = read(port,2,'uint8')';
                if (Head(1)==uint8(85))
                    switch(Head(2))
                        case 81 
                            a = read(port,3,'int16')'/32768*16 ;     
                            End = read(port,3,'uint8')';
                        case 82 
                            w = read(port,3,'int16')'/32768*2000 ;    
                            End = read(port,3,'uint8')';
                        case 83 
                            A = read(port,3,'int16')'/32768*180;
                            aa=[aa;a'];
                            ww = [ww;w'];
                            AA = [AA;A'];
                            tt = [tt;t];
                            pitch = A(2);
                            roll = A(1);
                            if (size(aa,1)>5*f)%clear history data
                                aa = aa(f:5*f,:);
                                ww = ww(f:5*f,:);
                                AA = AA(f:5*f,:);
                                tt = tt(f:5*f,:);
                            end
            
                            t=t+0.10;
                            End = read(port,3,'uint8')';
                            break
                    end    
                end
            end
            disp("pitch: ");
            disp(pitch);
            disp("roll: ");
            disp(roll);
            flush(port);
        end

        function [new_pitch, new_roll] = get_inclinometer_reading(comp, serial)
            current_pitch = evalin("base", "current_pitch");
            current_roll = evalin("base", "current_roll");
            same_point = true;
            [new_pitch, new_roll] = get_HWT905TTL_data(comp, serial);
            while same_point
                [new_pitch, new_roll] = get_HWT905TTL_data(comp, serial);
                if ~(current_pitch == new_pitch || current_roll == new_roll)
                  same_point = false;
                end
            end
            assignin("base", "current_pitch", new_pitch);
            assignin("base", "current_roll", new_roll);
        end

        function sig = serial_communication(comp, s, message)
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
            if(str2double(sig) == 86)
                assignin('base', 'kill_switch_pressed', true);
            end
        end

        function custom_write(comp, s, message)
            flush(s);
            writeline(s, message);
        end

        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end


    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoveForceGaugeStepperMotorsXmmButton
        function MoveForceGaugeStepperMotorsXmmButtonPushed(comp, event)
            try
                assignin("base", "kill_switch_pressed", false);
                left_mm = str2num(comp.MoveLeftStepperMotormmEditField.Value);
                disp(left_mm);
                right_mm = str2num(comp.MoveRightStepperMotormmEditField.Value);
                disp(right_mm);
                if right_mm > 100 || right_mm < -100 || left_mm > 100 || left_mm < -100
                   str = strcat("Please input millimeters inbetween -100 and 100. \n Inputed: \n Left = ",num2str(left_mm),"\n Right = ",num2str(right_mm));
                   fig = uifigure;
                   uialert(fig,str,'Input Error');
                   return;
                end
                sig = move_force_gauges(comp, left_mm, right_mm);
                disp(sig);
            catch e
               m = getReport(e,"extended");
               str = strcat("Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Move Force Gauges X mm');
            end
        end

        % Button pushed function: MoveForceGaugeSteppertoDesiredForceButton
        function MoveForceGaugeSteppertoDesiredForceButtonPushed(comp, event)
            try
                assignin("base", "kill_switch_pressed", false);
                force_lbs = str2num(comp.MovetoDesiredForcelbsEditField.Value);
                step_size = str2num(comp.StepSizemmForceEditField.Value);
                direction = comp.DirectionSwitch.Value;
                if force_lbs > 50 || force_lbs < 0
                   str = strcat("Please input force inbetween 0 lbs and 50 lbs. \n Inputed: = ",num2str(force_lbs));
                   fig = uifigure;
                   uialert(fig,str,'Input Error');
                   return;
                end
            if step_size>30 || step_size<=0
                str = strcat("Please input step inbetween 1 mm and 60 mm. \n Inputed: = ",num2str(step_size));
                fig = uifigure;
                uialert(fig,str,'Input Error');
                return;
            end
            sig = move_force_gauges_until_desired_force(comp, force_lbs, step_size, direction);
            disp(sig);
            catch e
               m = getReport(e,"extended");
               str = strcat("Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Desired Force');
            end
        end

        % Button pushed function: LevelForceGaugeStepperMotorsButton
        function LevelForceGaugeStepperMotorsButtonPushed(comp, event)
            try
                assignin("base", "kill_switch_pressed", false);
                desired_angle = str2num(comp.DesiredAngledegreesEditField.Value);
                precision_deg = str2num(comp.PrecisiondegreesEditField.Value);
                step_size = str2num(comp.StepSizemmLevelEditField.Value);
                if desired_angle > 45 || desired_angle < -45
                   str = strcat("Please input desired angle greater than -45 degrees and less than 45 degrees. \n Inputed: = ",num2str(desired_angle));
                   fig = uifigure;
                   uialert(fig,str,'Input Error');
                   return;
                end
                if precision_deg > 5 || precision_deg <= 0
                   str = strcat("Please input precision greater than 0 lbs and less than 5 lbs. \n Inputed: = ",num2str(precision_deg));
                   fig = uifigure;
                   uialert(fig,str,'Input Error');
                   return;
                end
                if step_size>10 || step_size<=0
                    str = strcat("Please input step inbetween 1 mm and 60 mm. \n Inputed: = ",num2str(step_size));
                    fig = uifigure;
                    uialert(fig,str,'Input Error');
                    return;
                end
                sig = level_force_gauges(comp, desired_angle, precision_deg, step_size);
                disp(sig);
            catch e
               m = getReport(e,"extended");
               str = strcat("Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Level Force Gauges');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 328 431];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.ForceGaugeStepperMotorsControllerPanel = uipanel(comp);
            comp.ForceGaugeStepperMotorsControllerPanel.Title = 'Force Gauge Stepper Motors Controller ';
            comp.MoveLeftStepperMotormmEditFieldLabel = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.MoveLeftStepperMotormmEditFieldLabel.Text = 'Move Left Stepper Motor (mm)';
            comp.MoveLeftStepperMotormmEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.MoveLeftStepperMotormmEditField.Placeholder = '0';
            comp.MoveRightStepperMotormmEditFieldLabel = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.MoveRightStepperMotormmEditFieldLabel.Text = 'Move Right Stepper Motor (mm)';
            comp.MoveRightStepperMotormmEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.MoveRightStepperMotormmEditField.Placeholder = '0';
            comp.MoveForceGaugeStepperMotorsXmmButton = uibutton(comp.ForceGaugeStepperMotorsControllerPanel, 'push');
            comp.MoveForceGaugeStepperMotorsXmmButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoveForceGaugeStepperMotorsXmmButtonPushed, true);
            comp.MoveForceGaugeStepperMotorsXmmButton.Text = 'Move Force Gauge Stepper Motors X mm';
            comp.LevelForceGaugeStepperMotorsButton = uibutton(comp.ForceGaugeStepperMotorsControllerPanel, 'push');
            comp.LevelForceGaugeStepperMotorsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LevelForceGaugeStepperMotorsButtonPushed, true);
            comp.LevelForceGaugeStepperMotorsButton.Text = 'Level Force Gauge Stepper Motors';
            comp.MovetoDesiredForcelbsEditFieldLabel = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.MovetoDesiredForcelbsEditFieldLabel.Text = 'Move to Desired Force (lbs)';
            comp.MovetoDesiredForcelbsEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.MovetoDesiredForcelbsEditField.Placeholder = '-1';
            comp.MoveForceGaugeSteppertoDesiredForceButton = uibutton(comp.ForceGaugeStepperMotorsControllerPanel, 'push');
            comp.MoveForceGaugeSteppertoDesiredForceButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoveForceGaugeSteppertoDesiredForceButtonPushed, true);
            comp.MoveForceGaugeSteppertoDesiredForceButton.Text = 'Move Force Gauge Stepper to Desired Force';
            comp.StepSizemmEditFieldLabel = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.StepSizemmEditFieldLabel.Text = 'Step Size (mm)';
            comp.StepSizemmForceEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.StepSizemmForceEditField.Placeholder = '0';
            comp.PrecisiondegreesEditFieldLabel = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.PrecisiondegreesEditFieldLabel.Text = 'Precision (+/- degrees)';
            comp.PrecisiondegreesEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.PrecisiondegreesEditField.Placeholder = '10';
            comp.StepSizemmEditField_2Label = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.StepSizemmEditField_2Label.Text = 'Step Size (mm)';
            comp.StepSizemmLevelEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.StepSizemmLevelEditField.Placeholder = '0';
            comp.DesiredAngledegreesEditFieldLabel = uilabel(comp.ForceGaugeStepperMotorsControllerPanel);
            comp.DesiredAngledegreesEditFieldLabel.Text = 'Desired Angle(degrees)';
            comp.DesiredAngledegreesEditField = uieditfield(comp.ForceGaugeStepperMotorsControllerPanel, 'text');
            comp.DesiredAngledegreesEditField.Placeholder = '90';
            comp.DirectionSwitch = uiswitch(comp.ForceGaugeStepperMotorsControllerPanel, 'slider');
            comp.DirectionSwitch.Items = {'Up', 'Down'};
            comp.DirectionSwitch.Value = 'Up';
        end
    end
end