classdef HomePage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AdvancedTestingButton    matlab.ui.control.Button
        SimplifiedTestingButton  matlab.ui.control.Button
        BLISTERLabsSkiFlexLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        function results = setSerial(comp, serial_name, port_name, port, baud)
            try
               assignin('base',port_name, []);
               assignin('base',port_name,port);
               assignin('base',serial_name, []);
               serial = serialport(port, baud);               
               assignin('base',serial_name, serial);
               %disp("serail set");
            catch e
               m = getReport(e,"extended");
               str = strcat("failed to connect serial. \n Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ise_ports_set = evalin( 'base', 'exist(''arduino_port'',''var'') == 1' );
            if ~ise_ports_set
                assignin("base", "arduino_port", "COM16");
                assignin("base", "ski_inclinometer_port", "COM12");
                assignin("base", "level_inclinometer_port", "COM11");
                assignin("base", "force_gauge_left_port", "COM7");
                assignin("base", "force_gauge_right_port", "COM8");
            end
        end

        % Button pushed function: SimplifiedTestingButton
        function SimplifiedTestingButtonPushed(app, event)
            %got to NameTest.mlapp
            try
                arduino_com = evalin("base", "arduino_port");
                setSerial(comp, "arduino_serial", "arduino_port", arduino_com, 115200);
                ski_inclinometer_com = evalin("base", "ski_inclinometer_port");
                setSerial(comp, "ski_inclinometer_serial", "ski_inclinometer_port", ski_inclinometer_com, 9600);
                level_inlcinometer_com = evalin("base", "level_inclinometer_port");
                setSerial(comp, "level_inclinometer_serial", "level_inlcinometer_port", level_inlcinometer_com, 9600);
                force_gauge_left_com = evalin("base", "force_gauge_left_port");
                setSerial(comp, "force_gauge_left_serial", "force_gauge_left_port", force_gauge_left_com, 9600);
                force_gauge_right_com = evalin("base", "force_gauge_right_port");
                setSerial(comp, "force_gauge_right_serial","force_gauge_right_port", force_gauge_right_com, 9600);

            catch e
               m = getReport(e,"extended");
               str = strcat("failed to connect serial. \n Exception: \n ", m);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end

            assignin("base", "test_interval_mm", 50);
            NameTest;
            delete(HomePage);
        end

        % Button pushed function: AdvancedTestingButton
        function AdvancedTestingButtonPushed(app, event)
            TestingUI;
            delete(HomePage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BLISTERLabsSkiFlexLabel = uilabel(app.UIFigure);
            app.BLISTERLabsSkiFlexLabel.Text = 'BLISTER Labs Ski Flex';
            app.SimplifiedTestingButton = uibutton(app.UIFigure, 'push');
            app.SimplifiedTestingButton.ButtonPushedFcn = createCallbackFcn(app, @SimplifiedTestingButtonPushed, true);
            app.SimplifiedTestingButton.Text = 'Simplified Testing';
            app.AdvancedTestingButton = uibutton(app.UIFigure, 'push');
            app.AdvancedTestingButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedTestingButtonPushed, true);
            app.AdvancedTestingButton.Text = 'Advanced Testing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomePage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end