classdef SetCOMs < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SetCOMsPanel                  matlab.ui.container.Panel
        DropDownForceRight            matlab.ui.control.DropDown
        DropDownForceLeft             matlab.ui.control.DropDown
        DropDownLevelInclinometer     matlab.ui.control.DropDown
        DropDownSkiInclinometer       matlab.ui.control.DropDown
        DropDownArduino               matlab.ui.control.DropDown
        ClearCOMPortsButton           matlab.ui.control.Button
        SetForceGaugeRightCOMButton   matlab.ui.control.Button
        SetForceGaugeLeftCOMButton    matlab.ui.control.Button
        SetLevelInclinomterCOMButton  matlab.ui.control.Button
        SetSkiInclinometerCOMButton   matlab.ui.control.Button
        SetArduinoCOMButton           matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = setSerial(comp, serial_name, port_name, port, baud)
            try
               assignin('base',port_name, []);
               assignin('base',port_name,port);
               assignin('base',serial_name, []);
               serial = serialport(port, baud);               
               assignin('base',serial_name, serial);
               %disp("serail set");
            catch e
               str = strcat("failed to connect serial. \n Exception: \n ", e);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: DropDownArduino
        function DropDownArduinoOpening(comp, event)
            % set all drop down options to avalible com ports
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownArduino.Items = freeports;
        end

        % Button pushed function: SetArduinoCOMButton
        function SetArduinoCOMButtonPushed(comp, event)
            %global arduino_serail;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownArduino.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownArduino.Placeholder)
                %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
               return;
            end
            % TestingUI.arduino_port = selectedCOM;
            %disp("option not selected, comport coing to be set");
            setSerial(comp, 'arduino_serial', 'arduino_port', selectedCOM, 115200);
            % comp.DropDownArduino.Items = {selectedCOM};
        end

        % Button pushed function: SetSkiInclinometerCOMButton
        function SetSkiInclinometerCOMButtonPushed(comp, event)
            %global inclinometer_front_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownSkiInclinometer.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownSkiInclinometer.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.inclinometer_port_front = selectedCOM;
            setSerial(comp, "ski_inclinometer_serial", "ski_inlinometer_port", selectedCOM, 9600);
            % comp.DropDownSkiInclinometer.Items = {selectedCOM};
        end

        % Button pushed function: SetLevelInclinomterCOMButton
        function SetLevelInclinomterCOMButtonPushed(comp, event)
            %global inclinometer_back_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownLevelInclinometer.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownLevelInclinometer.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.inclinometer_port_back = selectedCOM;
            setSerial(comp, "level_inclinometer_serial", "level_inlcinometer_port", selectedCOM, 9600);
            % comp.DropDownLevelInclinometer.Items = {selectedCOM};
        end

        % Button pushed function: SetForceGaugeLeftCOMButton
        function SetForceGaugeLeftCOMButtonPushed(comp, event)
            %global force_gage_left_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceLeft.Value;
            disp(class(selectedCOM));
            disp(class(comp.DropDownForceLeft.Placeholder));
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownForceLeft.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.force_gage1_port = selectedCOM;
            setSerial(comp, "force_gauge_left_serial","force_gauge_left_port", selectedCOM, 9600);
            % comp.DropDownForceLeft.Items = {selectedCOM};
        end

        % Button pushed function: SetForceGaugeRightCOMButton
        function SetForceGaugeRightCOMButtonPushed(comp, event)
            %global force_gage_right_serial;
            %get selected item from arduino dropdown
            selectedCOM = comp.DropDownForceRight.Value;
            %check if not default option
            if convertCharsToStrings(selectedCOM) == convertCharsToStrings(comp.DropDownForceRight.Placeholder)
               %error message
               str = strcat("COM not selected. \n Selected: \n ", selectedCOM);
               fig = uifigure;
               uialert(fig,str,'Serial Port Connection Error');
            end
            % TestingUI.force_gauge2_port = selectedCOM;
            setSerial(comp, "force_gauge_right_serial","force_gauge_right_port", selectedCOM, 9600);
            % comp.DropDownForceRight.Items = {selectedCOM};
        end

        % Button pushed function: ClearCOMPortsButton
        function ClearCOMPortsButtonPushed(comp, event)
            evalin('base', 'clear arduino_serial ski_inclinometer_serial level_inclinometer_serial force_gauge_left_serial force_gauge_right_serial');
            comp.DropDownArduino.Items = {"Options"};
            comp.DropDownSkiInclinometer.Items = {"Options"};
            comp.DropDownLevelInclinometer.Items = {"Options"};
            comp.DropDownForceLeft.Items = {"Options"};
            comp.DropDownForceRight.Items = {"Options"};
        end

        % Drop down opening function: DropDownSkiInclinometer
        function DropDownSkiInclinometerOpening(comp, event)
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownSkiInclinometer.Items = freeports;
        end

        % Drop down opening function: DropDownLevelInclinometer
        function DropDownLevelInclinometerOpening(comp, event)
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownLevelInclinometer.Items = freeports;
        end

        % Drop down opening function: DropDownForceLeft
        function DropDownForceLeftOpening(comp, event)
            freeports = serialportlist("available");
            comp.DropDownForceLeft.Items = freeports;
        end

        % Drop down opening function: DropDownForceRight
        function DropDownForceRightOpening(comp, event)
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownForceRight.Items = freeports;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            freeports = serialportlist("available");
%             assignin('base','freeports',freeports);
%             disp(class(freeports));
%             disp(freeports);
            comp.DropDownForceLeft.Items = freeports;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 331 247];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.SetCOMsPanel = uipanel(comp);
            comp.SetCOMsPanel.Title = 'Set COMs';
            comp.SetArduinoCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetArduinoCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetArduinoCOMButtonPushed, true);
            comp.SetArduinoCOMButton.Text = 'Set Arduino COM';
            comp.SetSkiInclinometerCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetSkiInclinometerCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetSkiInclinometerCOMButtonPushed, true);
            comp.SetSkiInclinometerCOMButton.Text = 'Set Ski Inclinometer COM';
            comp.SetLevelInclinomterCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetLevelInclinomterCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetLevelInclinomterCOMButtonPushed, true);
            comp.SetLevelInclinomterCOMButton.Text = 'Set Level Inclinomter COM';
            comp.SetForceGaugeLeftCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetForceGaugeLeftCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetForceGaugeLeftCOMButtonPushed, true);
            comp.SetForceGaugeLeftCOMButton.Text = 'Set Force Gauge Left COM';
            comp.SetForceGaugeRightCOMButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.SetForceGaugeRightCOMButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetForceGaugeRightCOMButtonPushed, true);
            comp.SetForceGaugeRightCOMButton.Text = 'Set Force Gauge Right COM';
            comp.ClearCOMPortsButton = uibutton(comp.SetCOMsPanel, 'push');
            comp.ClearCOMPortsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ClearCOMPortsButtonPushed, true);
            comp.ClearCOMPortsButton.Text = 'Clear COM Ports';
            comp.DropDownArduino = uidropdown(comp.SetCOMsPanel);
            comp.DropDownArduino.Items = {'Options'};
            comp.DropDownArduino.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownArduinoOpening, true);
            comp.DropDownArduino.Placeholder = 'Options';
            comp.DropDownArduino.Value = 'Options';
            comp.DropDownSkiInclinometer = uidropdown(comp.SetCOMsPanel);
            comp.DropDownSkiInclinometer.Items = {'Options'};
            comp.DropDownSkiInclinometer.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownSkiInclinometerOpening, true);
            comp.DropDownSkiInclinometer.Placeholder = 'Options';
            comp.DropDownSkiInclinometer.Value = 'Options';
            comp.DropDownLevelInclinometer = uidropdown(comp.SetCOMsPanel);
            comp.DropDownLevelInclinometer.Items = {'Options'};
            comp.DropDownLevelInclinometer.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownLevelInclinometerOpening, true);
            comp.DropDownLevelInclinometer.Placeholder = 'Options';
            comp.DropDownLevelInclinometer.Value = 'Options';
            comp.DropDownForceLeft = uidropdown(comp.SetCOMsPanel);
            comp.DropDownForceLeft.Items = {'Options'};
            comp.DropDownForceLeft.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownForceLeftOpening, true);
            comp.DropDownForceLeft.Placeholder = 'Options';
            comp.DropDownForceLeft.Value = 'Options';
            comp.DropDownForceRight = uidropdown(comp.SetCOMsPanel);
            comp.DropDownForceRight.Items = {'Options'};
            comp.DropDownForceRight.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @DropDownForceRightOpening, true);
            comp.DropDownForceRight.Placeholder = 'Options';
            comp.DropDownForceRight.Value = 'Options';
        end
    end
end