classdef SetTestNameComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TestDirectoryPanel              matlab.ui.container.Panel
        NameTestPanel                   matlab.ui.container.Panel
        ModelYearDropDown               matlab.ui.control.DropDown
        ModelYearDropDownLabel          matlab.ui.control.Label
        LengthcmDropDown                matlab.ui.control.DropDown
        LengthcmDropDownLabel           matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        SubmitButton_New                matlab.ui.control.Button
        SelectExistingTestNamePanel     matlab.ui.container.Panel
        SubmitButton_Existing           matlab.ui.control.Button
        ExistingTestNamesDropDown       matlab.ui.control.DropDown
        ExistingTestNamesDropDownLabel  matlab.ui.control.Label
        HasThisSkiAlreadyBeenTestedSwitchLabel  matlab.ui.control.Label
        HasThisSkiAlreadyBeenTestedSwitch  matlab.ui.control.Switch
    end

    
    methods (Access = public)
        
        function cleaned_dirs = getExsitingTestDirNames(comp)
            data_folder_path = 'G:\.shortcut-targets-by-id\1jaXuO8culNZtbUZ1rrY8TEjG_TDjo2f5\Blister Labs Ski Flex\Ski_Data\';
            l = dir(data_folder_path);
            dirs = {l.name};
            cleaned_dirs = dirs(1,3:end);
            cleaned_dirs(ismember(cleaned_dirs,'ReadMe.txt')) = {''};
            % use contians to fins csv, png, and txt files to remove if
            % neccessary
            cleaned_dirs = cleaned_dirs(~cellfun(@isempty, cleaned_dirs));
        end

        function result = checkTestPlaceholderNameError(comp, message, placeholder, input)
            disp("placeholder: ");
            disp(placeholder);
            disp("input: ");
            disp(input);
            if input == ""
               fig = uifigure;
               uialert(fig,message,'Ski Test Name Error');
               result = false;
               return;
            end
            result = true;
        end

        function result = checkIfDirNameExists(comp, dir_name)
            existing_dir_names = getExsitingTestDirNames(comp);
            if ismember(dir_name, existing_dir_names)
               str = strcat("Ski Test Name Already Exists. Please input new name or select existing name. \n Input: \n ", dir_name);
               fig = uifigure;
               uialert(fig,str,'Ski Test Name Error');
               result = false;
               return;
            end
            result = true;
        end

        function closest_test_names = check_for_similar_test_names(inputed_test_name, existing_test_names)
            % Initialize dictionary d with key = string, value = double
            d = dictionary;%containers.Map('KeyType', 'char', 'ValueType', 'double');%
        
            % Loop through each existing_name in existing_test_names
            for i = 1:length(existing_test_names)
                existing_name = existing_test_names{i};
                
                % Calculate the edit distance between inputed_test_name and existing_name
                dis = editDistance(inputed_test_name, existing_name);
                
                % Store the edit distance in the dictionary
                big = max(strlength(inputed_test_name), strlength(existing_name));
                dist = dis/big;
                d(existing_name) = dist;
            end
             
            % Order the dictionary by value in descending order
            global dic;
            sorted_d = sortDictByValue(d, 'ascend');
            dic = sorted_d;
        
          % Get all dictionary keys that have a value greater than 0.7
            closest_test_names = {};
            keys_sorted = keys(sorted_d);
            for i = 1:length(keys_sorted)
                key = keys_sorted{i};
                value = sorted_d(key);
                if value < 0.15
                    closest_test_names{end+1} = key;
                end
            end
        end
        
        
        function sortedDict = sortDictByValue(dict, sortOrder)
            % Function to sort a dictionary by value in ascending or descending order
            dictKeys = keys(dict);
            dictValues = values(dict);
            
            % Sort the dictionary values and get the corresponding indices
            [~, sortedIndices] = sort([dictValues(:)], sortOrder);
            
            % Reorder the dictionary keys based on the sorted indices
            sortedDictKeys = dictKeys(sortedIndices);
            sortedDictValues = dictValues(sortedIndices);
            
            % Create a new dictionary with the sorted keys and values
            sortedDict = dictionary(sortedDictKeys, sortedDictValues);%containers.Map(sortedDictKeys, sortedDictValues);%
        end
        
        function distance = manhattan_distance(str1, str2)
           if numel(str1) ~= numel(str2)
                error('Input strings must have the same length.');
            end
        
            ascii_str1 = double(str1);
            disp(ascii_str1)
            ascii_str2 = double(str2);
            disp(ascii_str2)
        
            distance = sum(abs(ascii_str1 - ascii_str2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.SelectExistingTestNamePanel.Enable = "on";
            comp.NameTestPanel.Enable = "off";
            % numbers = 100:200;
            % strings = arrayfun(@num2str, numbers, 'UniformOutput', false);
            % assignin("base", "strings", strings);
            % comp.LengthcmDropDown.Items = strings;
        end

        % Value changed function: HasThisSkiAlreadyBeenTestedSwitch
        function HasThisSkiAlreadyBeenTestedSwitchValueChanged(comp, event)
            value = comp.HasThisSkiAlreadyBeenTestedSwitch.Value;
            if value == "Yes"      
                comp.SelectExistingTestNamePanel.Enable = "on";
                comp.NameTestPanel.Enable = "off";
            else
                comp.SelectExistingTestNamePanel.Enable = "off";
                comp.NameTestPanel.Enable = "on";
            end
        end

        % Drop down opening function: ExistingTestNamesDropDown, 
        % ...and 1 other component
        function ExistingTestNamesDropDownOpening(comp, event)
            comp.ExistingTestNamesDropDown.Items = getExsitingTestDirNames(comp);
        end

        % Button pushed function: SubmitButton_Existing
        function SubmitButton_ExistingPushed(comp, event)
            % global test_directory_name;
            selectedSki = comp.ExistingTestNamesDropDown.Value;
            str = strcat("Ski not selected. \n Selected: \n ", selectedSki);
            res = checkTestPlaceholderNameError(comp, str, convertCharsToStrings(comp.ExistingTestNamesDropDown.Placeholder), convertCharsToStrings(selectedSki));
            if ~res; return; end;
            test_directory_name = selectedSki;   
            assignin('base','dir_name', test_directory_name);
            disp(selectedSki);
            str = strcat("Existing test directory set: ", selectedSki);
            fig = uifigure;
            uialert(fig,str,'Success Message','Icon','success');
        end

        % Button down function: TestDirectoryPanel
        function TestDirectoryPanelButtonDown(comp, event)
            comp.SelectExistingTestNamePanel.Enable = "off";
            comp.NameTestPanel.Enable = "off";
        end

        % Button down function: NameTestPanel
        function NameTestPanelButtonDown(comp, event)
            comp.StandardNamingPanel = "off";
        end

        % Callback function
        function LengthcmDropDownOpening(comp, event)
            %cm_arr = regexp(cellstr(sprintf('%d ',1:200)),' ','split');
            cm_arr = sprintfc('%d',100:200); % beware, undocumented!
            comp.LengthcmDropDown.Items = cm_arr;
        end

        % Button pushed function: SubmitButton_New
        function SubmitButton_NewPushed(comp, event)
            %global test_directory_name
            manufacturer = convertCharsToStrings(comp.ManufacturerEditField.Value);
            model = convertCharsToStrings(comp.ModelNameEditField.Value);
            year = convertCharsToStrings(comp.ModelYearDropDown.Value);
            length = convertCharsToStrings(comp.LengthcmDropDown.Value);
            res = checkTestPlaceholderNameError(comp, "manufacturer name not inputted", convertCharsToStrings(comp.ManufacturerEditField.Placeholder), manufacturer);
            if ~res; return; end;
            res = checkTestPlaceholderNameError(comp, "model name not inputted", convertCharsToStrings(comp.ModelNameEditField.Placeholder), model);
            if ~res; return; end;
            % res = checkTestPlaceholderNameError(comp, "model year was not inputted", convertCharsToStrings(comp.ModelYearEditField.Placeholder), year);
            % if ~res; return; end;
            % res = checkTestPlaceholderNameError(comp, "model length was not inputted", convertCharsToStrings(comp.LengthcmDropDown.Placeholder), length);
            % if ~res; return; end;
            dir_name = strcat(manufacturer,"_",model,"_",year,"_",length);
            res = checkIfDirNameExists(comp, dir_name);
            if ~res; return; end;
            test_directory_name = dir_name;
            
            existing_dirs = getExsitingTestDirNames(comp);
            closest_dirs = check_for_similar_test_names(test_directory_name, existing_dirs);
            
            if ~isempty(closest_dirs)
                assignin('base','input_name', dir_name);
                uiwait(SimilarTestNamesComp);
            else
                assignin('base','dir_name',test_directory_name);
            end

            dir_name = evalin('base', 'dir_name');
            disp(dir_name);
            str = strcat("New test directory has been made: ", dir_name);
            fig = uifigure;
            uialert(fig,str,'Success Message','Icon','success');
        end

        % Drop down opening function: ModelYearDropDown
        function ModelYearDropDownOpening(comp, event)
            year_arr = sprintfc('%d',1990:2030); % beware, undocumented!
            comp.ModelYearDropDown.Items = year_arr;
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 385 448];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestDirectoryPanel = uipanel(comp);
            comp.TestDirectoryPanel.Title = 'Test Directory ';
            comp.TestDirectoryPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @TestDirectoryPanelButtonDown, true);
            comp.HasThisSkiAlreadyBeenTestedSwitch = uiswitch(comp.TestDirectoryPanel, 'slider');
            comp.HasThisSkiAlreadyBeenTestedSwitch.Items = {'Yes', 'No'};
            comp.HasThisSkiAlreadyBeenTestedSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @HasThisSkiAlreadyBeenTestedSwitchValueChanged, true);
            comp.HasThisSkiAlreadyBeenTestedSwitch.Value = 'Yes';
            comp.HasThisSkiAlreadyBeenTestedSwitchLabel = uilabel(comp.TestDirectoryPanel);
            comp.HasThisSkiAlreadyBeenTestedSwitchLabel.Text = 'Has This Ski Already Been Tested';
            comp.SelectExistingTestNamePanel = uipanel(comp.TestDirectoryPanel);
            comp.SelectExistingTestNamePanel.Title = 'Select Existing Test Name';
            comp.ExistingTestNamesDropDownLabel = uilabel(comp.SelectExistingTestNamePanel);
            comp.ExistingTestNamesDropDownLabel.Text = 'Existing Test Names';
            comp.ExistingTestNamesDropDown = uidropdown(comp.SelectExistingTestNamePanel);
            comp.ExistingTestNamesDropDown.Items = {'Options'};
            comp.ExistingTestNamesDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @ExistingTestNamesDropDownOpening, true);
            comp.ExistingTestNamesDropDown.Placeholder = 'Options';
            comp.ExistingTestNamesDropDown.Value = 'Options';
            comp.SubmitButton_Existing = uibutton(comp.SelectExistingTestNamePanel, 'push');
            comp.SubmitButton_Existing.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButton_ExistingPushed, true);
            comp.SubmitButton_Existing.Text = 'Submit';
            comp.NameTestPanel = uipanel(comp.TestDirectoryPanel);
            comp.NameTestPanel.Title = 'Name Test';
            comp.NameTestPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @NameTestPanelButtonDown, true);
            comp.SubmitButton_New = uibutton(comp.NameTestPanel, 'push');
            comp.SubmitButton_New.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButton_NewPushed, true);
            comp.SubmitButton_New.Text = 'Submit';
            comp.ManufacturerEditFieldLabel = uilabel(comp.NameTestPanel);
            comp.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            comp.ManufacturerEditField = uieditfield(comp.NameTestPanel, 'text');
            comp.ManufacturerEditField.Placeholder = 'Enter Manufacturer';
            comp.ModelNameEditFieldLabel = uilabel(comp.NameTestPanel);
            comp.ModelNameEditFieldLabel.Text = 'Model Name';
            comp.ModelNameEditField = uieditfield(comp.NameTestPanel, 'text');
            comp.ModelNameEditField.Placeholder = 'Enter Model Name';
            comp.LengthcmDropDownLabel = uilabel(comp.NameTestPanel);
            comp.LengthcmDropDownLabel.Text = 'Length (cm)';
            comp.LengthcmDropDown = uidropdown(comp.NameTestPanel);
            comp.LengthcmDropDown.Items = {'Options'};
            comp.LengthcmDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @ExistingTestNamesDropDownOpening, true);
            comp.LengthcmDropDown.Value = 'Options';
            comp.ModelYearDropDownLabel = uilabel(comp.NameTestPanel);
            comp.ModelYearDropDownLabel.Text = 'Model Year';
            comp.ModelYearDropDown = uidropdown(comp.NameTestPanel);
            comp.ModelYearDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @ModelYearDropDownOpening, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end