classdef TestingControllerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TestingPanel                  matlab.ui.container.Panel
        EditField                     matlab.ui.control.NumericEditField
        SetNumberofTestsButton        matlab.ui.control.Button
        clearDataButton               matlab.ui.control.Button
        KillSwitchPressedGo           matlab.ui.control.Button
        KillSwitchPressedDropDown     matlab.ui.control.DropDown
        KillswitchpressedSelecthowtoproceedDropDownLabel  matlab.ui.control.Label
        TestNameLabel                 matlab.ui.control.Label
        SaveTestButton                matlab.ui.control.Button
        RunSelectedTestButton         matlab.ui.control.Button
        SelectTesttoRunDropDown       matlab.ui.control.DropDown
        SelectTesttoRunDropDownLabel  matlab.ui.control.Label
        GJTestAxis                    matlab.ui.control.UIAxes
        EITestAxis                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% AUTOMATION FUCNTIONS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function return_matrix = sensor_automation(comp, test_interval_mm, direction) %inclinometer_serial
            
                ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                arduino_serial  = evalin("base", "arduino_serial");
                force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
                data_matrix = zeros(0, 4);
                stop_num=0;
                flush(ski_inclinometer_serial);
                [pitch, roll] = get_HWT905TTL_data(comp,ski_inclinometer_serial); % for flushing the inclinometer
                assignin("base", "current_pitch", pitch);
                assignin("base", "current_roll", roll);
                kill_switch_pressed = evalin("base", "kill_switch_pressed");

                while stop_num~=42 && stop_num ~= 86 && kill_switch_pressed ~= 1
                    %collect data
                    pause(.125); 
                    flush(ski_inclinometer_serial);
                    disp("waiting for incl data");
                    [pitch, roll] = get_inclinometer_reading(comp,ski_inclinometer_serial);
                    disp("waiting for force data");
                    [force1, force2]=force_average(comp,force_gauge_left_serial, force_gauge_right_serial, 1);
            
                    row_entry = [pitch, roll, force1, force2];               
                    data_matrix = [data_matrix;row_entry];
                    disp("data_matrix: ");
                    disp(data_matrix);
                                    
                    %MOVE SENSOR
                    disp("waiting for move");
                    sig = move_x_mm(comp, -1*test_interval_mm, direction, arduino_serial);
                    disp(sig);
                    stop_num = str2double(sig);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
                if kill_switch_pressed
                    current_test = evalin('base', 'current_test');
                    evalin('base', strcat("clear ", current_test));
                end
                return_matrix = data_matrix;
        end

        function ret_signal = move_x_mm(comp, dis_mm, dir, s)
            MOVE_X = 4;
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_X),",",num2str(dis_mm),",",num2str(dir));
                ret_signal = serial_communication(comp, s, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true); 
                    comp.KillSwitchPressedDropDown.Enable = "on";
                    comp.KillSwitchPressedDropDown.Visible = "on";
                    comp.KillSwitchPressedGo.Enable = "on";
                    comp.KillSwitchPressedGo.Visible = "on";
                end
                flush(s);
            else
                ret_signal = 86;
            end
        end

        function ret_signal = return_to_start(comp, s)
            MOVE_TO_START = 2;
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_TO_START),",0,1");
                ret_signal = serial_communication(comp, s, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true); 
                    comp.KillSwitchPressedDropDown.Enable = "on";
                    comp.KillSwitchPressedDropDown.Visible = "on";
                    comp.KillSwitchPressedGo.Enable = "on";
                    comp.KillSwitchPressedGo.Visible = "on";
                end
                flush(s);
            else
                ret_signal = 86;
            end
        end 

        function ret_signal = set_current_position(comp, pos)
            SET_CURRENT_POS = 6;
            arduino_serial = evalin('base', 'arduino_serial');
            serial_string = strcat(num2str(SET_CURRENT_POS),",",num2str(pos),",0");
            %ret_signal = serial_communication(s, serial_string);
            custom_write(comp, arduino_serial, serial_string);
            ret_signal = custom_read(comp, arduino_serial);
            flush(arduino_serial);
        end

        function ret_signal = move_force_gauges(comp, s, left_mm, right_mm)
            MOVE_FORCE_GAUGES = 24;
            KILL_SWITCH_SIGNAL = 86;
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if ~kill_switch_pressed
                serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(left_mm),",",num2str(right_mm));
                ret_signal = serial_communication(comp, s, serial_string);
                if str2num(ret_signal) == KILL_SWITCH_SIGNAL
                    assignin("base","kill_switch_pressed",true); 
                    comp.KillSwitchPressedDropDown.Enable = "on";
                    comp.KillSwitchPressedDropDown.Visible = "on";
                    comp.KillSwitchPressedGo.Enable = "on";
                    comp.KillSwitchPressedGo.Visible = "on";
                end
                flush(s);
            else
                ret_signal = 86;
            end

        end
        
        function ret_signal = move_force_gauges_until_desired_force(comp, s, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size, direction, precision)
            MOVE_FORCE_GAUGES = 24;
            KILL_SWITCH_SIGNAL = 86;
            [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            ret_signal = 24;
            if direction == "up"
                while (force_left < (desired_force-precision) || force_right < (desired_force-precision)) && ~(str2double(ret_signal) == 86) && kill_switch_pressed ~= 86
                    serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(step_size),",",num2str(step_size));
                    ret_signal = serial_communication(comp, s, serial_string);
                    [force_left, force_right] = force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
            elseif direction == "down" 
                disp("attempting to go down");
                if force_right < 20 || force_left < 20
                    step_size = 10;
                end
                if force_right < 10 || force_left < 10
                    step_size = 5;
                end
                if force_right < 5 || force_left < 5
                    step_size = 2;
                end
                while (force_left > (desired_force+precision) || force_right > (desired_force+precision)) && ~(str2double(ret_signal) == 86) && kill_switch_pressed ~= 86
                    serial_string = strcat(num2str(MOVE_FORCE_GAUGES),",",num2str(-1*step_size),",",num2str(-1*step_size));
                    ret_signal = serial_communication(comp, s, serial_string);
                    disp(ret_signal);
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);

                    if force_right < 20 || force_left < 20
                        step_size = 10;
                    end
                    if force_right < 10 || force_left < 10
                        step_size = 5;
                    end
                    if force_right < 5 || force_left < 5
                        step_size = 2;
                    end
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                end
            end
            
            flush(s);
        end
        
        function ret_signal = level_force_gauges(comp, s, desired_angle, precision, inclinometer, step_size)
            MOVE_FORCE_GAUGES = 24;
            KILL_SWITCH_SIGNAL = 86;
            [pitch, roll] = get_HWT905TTL_data(comp, inclinometer);
            pitch = pitch *-1;
            assignin("base", "current_pitch", pitch);
            assignin("base", "current_roll", roll);
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            %desired_angle = desired_angle + 90;

            % disp(strcat("pitch: ", num2str(pitch)));
            % disp(strcat("roll: ", num2str(roll)));
            ret_signal = 24;
            if abs(pitch - desired_angle) < 10
                step_size = 7;
            end
            if abs(pitch - desired_angle) < 5
                step_size = 5;
            end
            if abs(pitch - desired_angle) < 1
                step_size = 1;
            end
            while ~(pitch < (desired_angle + precision) && pitch > (desired_angle - precision)) && ~(str2double(ret_signal) == 86) && kill_switch_pressed ~= 86;
                % step_size = gradient descent step size adjustment(roll, desired_angle, step_size)
                message = make_message(comp, int2str(MOVE_FORCE_GAUGES), pitch, desired_angle, precision, step_size);
                ret_signal = serial_communication(comp, s, message);
                [pitch, roll] = get_inclinometer_reading(comp, inclinometer);
                pitch = pitch *-1;
                disp(strcat("pitch: ", num2str(pitch)));
                disp(strcat("roll: ", num2str(roll)));
                if abs(pitch - desired_angle) < 10
                    step_size = 7;
                end
                if abs(roll - desired_angle) < 5
                    step_size = 5;
                end
                if abs(pitch - desired_angle) < 1
                    step_size = 1;
                end
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
            end
            flush(s);
        end
        
        function message = make_message(comp, m, actual_angle, desired_angle, precision, step_size)
            message = m;
            if actual_angle <= desired_angle+precision && actual_angle >= desired_angle-precision % make this a fuction that takes in actual and desired, concatenates and retruns message
                  message = strcat(message,',0',',0');
            elseif actual_angle < desired_angle
                  message = strcat(message,',0',',',num2str(step_size)); 
            else
                  message = strcat(message,',',num2str(step_size),',0'); 
            end 
        end

        function ret_mm = get_distance_from_start(comp, s)
            GET_CURRENT_POSITION = 14;
            serial_string = strcat(num2str(GET_CURRENT_POSITION),",0,1");
            %ret_mm = serial_communication(s, serial_string);
            custom_write(comp, s, serial_string);
            ret_mm = custom_read(comp, s);
            ret_mm = str2double(ret_mm);
            flush(s);
        end

        function ret_sig = reset_kill_state(comp, s)
            RESET_KILL_SWITCH = 17;
            serial_string = strcat(num2str(RESET_KILL_SWITCH),",0,0");
            ret_sig = serial_communication(comp, s, serial_string);
        end

        function sig = serial_communication(comp, s, message)
            disp(message);
            custom_write(comp, s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(comp, s);
            if(str2double(sig) == 86)
                assignin('base', 'kill_switch_pressed', true);
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedDropDown.Visible = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                comp.KillSwitchPressedGo.Visible = "on";
            end
        end
        
        function custom_write(comp, s, message)
            flush(s);
            writeline(s,message);
        end
        
        function out = custom_read(comp, s)
            out = readline(s);
            flush(s);
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% SAVE DATA FUNCTIONS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%

        function temp_save_single_test(comp, data_matrix, type)
            %relative_save_path = strcat('..\..\0_Data\Temp_Data_Folder\');
            current_folder = pwd;
            split_name = split(current_folder,"\Ski_Flex\");
            relative_save_path = strcat(split_name(1),"\Ski_Flex\0_Data\Temp_Data_Folder\");

            if ~exist(relative_save_path, 'dir')
               mkdir(relative_save_path);
            end
            column_names = {'pitch', 'roll', 'forceLeft', 'forceRight'};
            data = [column_names; num2cell(data_matrix)];
            writecell(data, strcat(relative_save_path, "\",type,".csv" ));    
        end

        function temp_save_data(comp, data_matrix, type)
            current_folder = pwd;
            split_name = split(current_folder,"\Ski_Flex\");
            relative_save_path = strcat(split_name(1),"\Ski_Flex\0_Data\Temp_Data_Folder\");

            if ~exist(relative_save_path, 'dir')
               mkdir(relative_save_path);
            end
            writematrix(data_matrix, strcat(relative_save_path, "\",type,".csv" ));    

        end

        function [m1, m2] = check_and_adjust_size(comp, matrix1, matrix2)%, truncate_location)
            if size(matrix1,1) > size(matrix2,1)
                matrix1 = truncate_matrix(comp, matrix1, size(matrix1,1)-size(matrix2,1));%, truncate_location);
            elseif size(matrix1,1) < size(matrix2,1)
                matrix2 = truncate_matrix(comp, matrix2, size(matrix2,1)-size(matrix1,1));%, truncate_location);
            end
            m1 = matrix1;
            m2 = matrix2;
        end
        
        function truncated = truncate_matrix(comp, matrix, num_rows)%, truncate_location)
            for i = size(matrix,1):-1:size(matrix,1)-num_rows+1
                matrix(i, :) = [];
            end
            truncated = matrix;
        end

        function save_data_clear_temp(comp, dir_name)
            relative_dir_path = strcat('G:\.shortcut-targets-by-id\1jaXuO8culNZtbUZ1rrY8TEjG_TDjo2f5\Blister Labs Ski Flex\Ski_Data\', dir_name);
            if ~exist(relative_dir_path, 'dir')
               mkdir(relative_dir_path);
            end
            S = dir(relative_dir_path);
            N = nnz(~ismember({S.name},{'.','..'})&[S.isdir]);
            test_dir_name = strcat(num2str(N),"_test");
            relative_save_path = strcat(relative_dir_path,'\',test_dir_name);
            mkdir(relative_save_path);
            temp_folder_dir = strcat('..\..\..\0_Data\Temp_Data_Folder\');
            filePattern = fullfile(temp_folder_dir, '*.*');
            copyfile(filePattern, relative_save_path);
            rmdir(temp_folder_dir, 's');
            if ~exist(temp_folder_dir, 'dir')
               mkdir(temp_folder_dir);
            end
        end

        function temp_save_plot_and_points(comp, x_points, y_points, plot_title)
            relative_save_path = strcat('..\..\..\0_Data\Temp_Data_Folder\');
            if ~exist(relative_save_path, 'dir')
               mkdir(relative_save_path);
            end
            hFig = plot(x_points, y_points);
            title(plot_title);
            xlabel("Milimeters");
            ylabel("N*(m^2)");
            points_matrix = [x_points', y_points'];
            column_names = {"milimeters", "N*(m^2)"};
            data = [column_names; num2cell(points_matrix)];
            writecell(data, strcat(relative_save_path, "\",plot_title,"_points.csv" ));
            saveas(hFig, strcat(relative_save_path, "\",plot_title,"_graph.png"));
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% SENSOR READING FUNCTIONS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [pitch, roll] = get_HWT905TTL_data(comp, port)
            f = 20;%DataFrequce
            aa=[0 0 0];
            ww=[0 0 0];
            AA = [0 0 0];
            t=0;
            tt = 0;
            a=[0 0 0]';
            w=[0 0 0]';
            A=[0 0 0]';
            while(1)
                Head = read(port,2,'uint8')';
                if (Head(1)==uint8(85))
                    switch(Head(2))
                        case 81 
                            a = read(port,3,'int16')'/32768*16 ;     
                            End = read(port,3,'uint8')';
                        case 82 
                            w = read(port,3,'int16')'/32768*2000 ;    
                            End = read(port,3,'uint8')';
                        case 83 
                            A = read(port,3,'int16')'/32768*180;
                            aa=[aa;a'];
                            ww = [ww;w'];
                            AA = [AA;A'];
                            tt = [tt;t];
                            pitch = A(1);
                            roll = A(2);
                            if (size(aa,1)>5*f)%clear history data
                                aa = aa(f:5*f,:);
                                ww = ww(f:5*f,:);
                                AA = AA(f:5*f,:);
                                tt = tt(f:5*f,:);
                            end
            
                            t=t+0.10;
                            End = read(port,3,'uint8')';
                            break
                    end    
                end
            end
            flush(port);
        end

        function [new_pitch, new_roll] = get_inclinometer_reading(comp, serial)
            current_pitch = evalin("base", "current_pitch");
            current_roll = evalin("base", "current_roll");
            same_point = true;
            [new_pitch, new_roll] = get_HWT905TTL_data(comp, serial);
            count = 0;
            while same_point && count < 10
                [new_pitch, new_roll] = get_HWT905TTL_data(comp, serial);
                if ~(current_pitch == new_pitch || current_roll == new_roll)
                  same_point = false;
                end
                count = count +1;
            end
            assignin("base", "current_pitch", new_pitch);
            assignin("base", "current_roll", new_roll);
        end

        function [force1,force2] = force_data(comp, serial1,serial2)
            %connects and reads first sensor
            %serial1=serialport(portname1,9600); %connects to serial port 
            write(serial1,'?','string');        %queries serial port (required to obtain data)
            force1=read(serial1,5,"string");    %reads data point
        
            %connects and read second sensor
            %serial2=serialport(portname2,9600); %connects to serial port
            write(serial2,'?','string');        %queries serial port (required to obtain data)
            force2=read(serial2,5,"string");    %reads data point
        
            %converts and outputs data for first sensor
            force1=strrep(force1,' ','');       %removes any spaces in read data
            force1=strrep(force1,'l','1');      %changes L to 1 (weird data read)
            force1=str2double(force1);          %converts data to double
        
            %converts and outputs data for second sensor
            force2=strrep(force2,' ','');       %removes any spaces in read data
            force2=strrep(force2,'l','1');      %changes L to 1 (weird data read)
            force2=str2double(force2);          %converts data to double
            flush(serial1);
            flush(serial2);
        end
        
        % gets the average force data (written by fin)
        function [force1,force2] = force_average(comp, ser1, ser2,average)  
            for i=1:average
                [force1(i),force2(i)]=force_data(comp, ser1,ser2);
            end
            force1=mean(force1);
            force2=mean(force2);
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%
        %%% CALCULATION CODE %%%
        %%%%%%%%%%%%%%%%%%%%%%%%

        function [X_points, EI_points] = get_EI_points(comp, data_matrix_unloaded, data_matrix_loaded, test_interval_mm)
                step_size_m = test_interval_mm/1000;
                %read the profile(unweighted pitch)
                profile = data_matrix_unloaded(:,1);
        
                %read the flex (weighted pitch)
                flex = data_matrix_loaded(:,1);
        
                %read forces from strain guages, and subtract the "unweighted" from
                %weighted to get a net force
                %force 1 is left, force 2 is right
                force1 = data_matrix_loaded(:,3)-data_matrix_unloaded(:,3);
                force2 = data_matrix_loaded(:,4)-data_matrix_unloaded(:,4);
                
                % Variables to setup Manually
                %measurements is the number of lines measured per round, = length/4
                measurements = length(flex);
        
                %rollermass = lass of rollers imparting force on skis in lbs
                rollermass = 18;
        
                %initialOffset = distance from applied load or clamp at the tip to the
                %first measurement in meters
                initialOffSet = .14 - step_size_m;
                
                %set empty string for moments
                moment = zeros(measurements,1);
                dTheta = zeros(measurements,1);
                
                % Displacement
                %displacement in radians
                displacement = zeros(1,measurements+1)';
                displacement(1:measurements) = deg2rad(profile - flex);
                assignin("base","ei_displacement",displacement);
        
                % Net Force
                %net force in lbs
                forceNet = force1+force2-rollermass;
         
                assignin("base","ei_distance_from_tip",[]);
                
                % EI calcuation
                EI = zeros(measurements,1);
                for n = 2:(measurements)
                    dTheta(n) = dirTheta(comp, n,displacement, step_size_m);
                    moment(n) = momentz(comp, n-1,forceNet,initialOffSet, measurements, test_interval_mm);
                    EI(n) = moment(n)/dTheta(n);
                end
                assignin("base", "ei_dtheta", dTheta);
                assignin("base","ei_moment",moment);
                EI_points = EI(3:measurements-1);
                X_points = linspace(0, (size(EI_points,1)-1)*test_interval_mm, size(EI_points,1));
                EI_points = EI_points';
        end
        
        function [X_points, GJ_points] = get_GJ_points(comp, data_matrix_unloaded, data_matrix_torsion, test_interval_mm)
                step_size_m = test_interval_mm/1000;
        
                %read the profile(unweighted pitch)
                tProf = data_matrix_unloaded(:,1);
        
                %read the flex (weighted pitch)
                rotation = data_matrix_torsion(:,1);
        
                %read forces from strain guages, and subtract the "unweighted" from
                %weighted to get a net force
                %force 1 is left, force 2 is right
                force1 = data_matrix_torsion(:,3)-data_matrix_unloaded(:,3);
                force2 = data_matrix_torsion(:,4)-data_matrix_unloaded(:,4);
        
                %measurements is the number of lines measured per round, = length/4
                measurements = length(rotation);
        
                %rollermass = lass of rollers imparting force on skis in lbs
                rollermass = 18;
        
                %initialOffset = distance from applied load or clamp at the tip to the
                %first measurement in meters
                initialOffSet = .14 - step_size_m;
        
                %set empty string for moments
                moment = zeros(measurements,1);
                dTheta = zeros(measurements,1);
                EIDistanceFromTip = zeros(measurements:1);
        
                % Displacement
                %displacement in radians
                displacement = zeros(1,measurements+1)';
                displacement(1:measurements) = deg2rad(tProf - rotation);
                assignin("base","gj_displacement", displacement);
        
                % Net Torque
                %net force in lbs
                torqueNet = abs(force1-force2)*3.75/12*1.356;
        
                % GJ calcuation
                GJ = zeros(measurements,1);
                for n = 2:(measurements)
                    dTheta(n) = dirTheta(comp, n,displacement, step_size_m);
                    GJ(n) = torqueNet(n)/dTheta(n);
                end
        
                assignin("base", "gj_dtheta",dTheta);
                assignin("base", "gj_moment", moment);
                GJ_points = abs(GJ(2:measurements-1));
                X_points = linspace(0, (size(GJ_points,1)-1)*test_interval_mm, size(GJ_points,1));
                GJ_points = GJ_points';
        end
        
        
        function [moment] = momentz(comp, n, forceNet,initialOffSet,measurements,test_interval_mm)
            moment = (forceNet(n) * distanceFromTip(comp, n,initialOffSet,measurements,test_interval_mm)) *4.448/2;
        end
        
        % Distance From Tip
        function [distanceFromTip] = distanceFromTip(comp, n,initialOffSet,measurements,test_interval_mm)
            %the distance of the measured pitch form the applied load in inches
            ei_distance_from_tip = evalin("base", "ei_distance_from_tip");
            distanceFromTip = (measurements/2 -abs(n-measurements/2))*test_interval_mm/1000+initialOffSet;
            ei_distance_from_tip(end+1) = distanceFromTip;
            assignin("base","ei_distance_from_tip", ei_distance_from_tip);
        end
        
        % Change in Theta at X
        function [dTheta] = dirTheta(comp, n,displacement, step_size_m)
            %calcualte the change in theta (the displacement) at a given x location
            %39.37 converts inches to meters
            theta = abs(displacement(n-1)-displacement(n))+abs(displacement(n)-displacement(n+1));
            dTheta = theta/(2*step_size_m);%*39.37/4; %in radians/meter
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% HELPER FUNCTIONS FOR CALL BACKS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function results = run_unloaded_test(comp)
            try
                KILL_SWITCH_SIGNAL = 86;
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
                assignin('base', "current_test", "unloaded");
                if ~kill_switch_pressed
                    pass = requirment_check(comp, false);
                    if ~pass
                        return;
                    end
    
                    ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                    level_inclinometer_serial = evalin("base","level_inclinometer_serial");
                    arduino_serial  = evalin("base", "arduino_serial");
                    force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                    force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
                    
                    distance_from_start = get_distance_from_start(comp, arduino_serial);
                    if distance_from_start ~= 0
                        ret_sig = return_to_start(comp, arduino_serial);
                    end
    
                    [pitch, roll] = get_HWT905TTL_data(comp, level_inclinometer_serial);
                    pitch = pitch *-1;
                    assignin("base", "current_pitch", pitch);
                    assignin("base", "current_roll", roll);
                    desired_angle = 0;
                    precision = 0.15;
                    step_size_level = 15;
                    disp(strcat("roll: ", num2str(roll)));
                    disp(strcat("pitch: ", num2str(pitch)));
                    if ~(pitch < desired_angle+precision && pitch > (desired_angle-precision))
                        disp("attempting to level force gauges");
                        ret_sig = level_force_gauges(comp, arduino_serial, desired_angle, precision, level_inclinometer_serial, step_size_level);
                    end 
                    
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    desired_force = 0;
                    precision = 0.3;
                    step_size_force = 10;
                    if force_left > desired_force+precision || force_right > desired_force+precision
                        disp("attempting to move force gauges to desired force");
                        ret_sig = move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size_force,"down", precision);
                    end
                    
                    test_interval_mm = evalin("base", "test_interval_mm");
                    direction = 0;
                    data_matrix_unloaded = sensor_automation(comp,test_interval_mm, direction);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");

                    pause(2);     
                    if (kill_switch_pressed == 0)
                        temp_save_single_test(comp,data_matrix_unloaded, "data_matrix_unloaded");
                        assignin('base','unloaded', data_matrix_unloaded);
                        % test_distance_mm = size(data_matrix_unloaded,1)*test_interval_mm;
                        % dist_between_mm = 863; %change when distance between inclinomters change

                        temp_save_single_test(comp,data_matrix_unloaded, "Unloaded");
                        sig = return_to_start(comp, arduino_serial);
                        pause(2);
                        set_current_position(comp, 0);
                    end
                else
                    ret_sig = 86;
                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   LOADED    ",m),'Run Test Error');
            end
        end

        function result = run_loaded_test(comp)
            try

                KILL_SWITCH_SIGNAL = 86;
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
                assignin('base', "current_test", "loaded");
                if ~kill_switch_pressed
                    pass = requirment_check(comp, true);
                    if ~pass
                        return;
                    end
    
                    ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                    level_inclinometer_serial = evalin("base","level_inclinometer_serial");
                    arduino_serial  = evalin("base", "arduino_serial");
                    force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                    force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
        
                    distance_from_start = get_distance_from_start(comp, arduino_serial);
                    if distance_from_start ~= 0
                        disp(distance_from_start);
                        disp("returning to start");
                        return_to_start(comp, arduino_serial);
                    end
        
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    desired_force = 25;
                    precision = 0.3;
                    step_size_force = 30;
                    if force_left < desired_force-precision || force_right < desired_force-precision
                        move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size_force,"up", precision);
                    end
                    %get force gauge distance from start
                    %take difference between ditance and 100 mm
                    %move up the difference
                    %pause(1);
        
                    [pitch, roll] = get_HWT905TTL_data(comp, level_inclinometer_serial);
                    pitch = pitch *-1;
                    assignin("base", "current_pitch", pitch);
                    assignin("base", "current_roll", roll);
                    desired_angle = 0;
                    precision = 0.10;
                    disp(strcat("roll: ", num2str(roll)));
                    disp(strcat("pitch: ", num2str(pitch)));
                    step_size_level = 15;
                    if ~(pitch < (desired_angle+precision) && pitch > (desired_angle-precision))
                        disp('attempting to level force gauges');
                        level_force_gauges(comp, arduino_serial, desired_angle, precision, level_inclinometer_serial, step_size_level);
                    end

                    test_interval_mm = evalin("base", "test_interval_mm");
                    direction = 0;
                    data_matrix_loaded = sensor_automation(comp, test_interval_mm, direction);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                    pause(2);
         
                    if (kill_switch_pressed == 0)
                        temp_save_single_test(comp, data_matrix_loaded, "data_matrix_loaded");

                        data_matrix_unloaded = evalin("base", "unloaded");
                        [unloaded, loaded] = check_and_adjust_size(comp,data_matrix_unloaded, data_matrix_loaded);%, truncate_from_bottom);
                        assignin("base", "loaded", loaded);

                        temp_save_single_test(comp, loaded, "Loaded");
                        [ei_x_points, ei_y_points] = get_EI_points(comp, unloaded, loaded, test_interval_mm);
                        assignin("base", "ei_x_points", ei_x_points);
                        assignin("base", "ei_y_points", ei_y_points);
                        temp_save_plot_and_points(comp, ei_x_points, ei_y_points, 'EI');
                        graph_ei_on_ui(comp, ei_x_points, ei_y_points);
                        ei_theta = evalin("base", "ei_dtheta");
                        ei_moment = evalin("base","ei_moment");
                        temp_save_data(comp, ei_theta, 'ei_theta');
                        temp_save_data(comp, ei_moment, 'ei_moment');
                        sig = return_to_start(comp, arduino_serial);
                        pause(2);
                        set_current_position(comp, 0);

                    end
                else
                    sig = 86;

                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   LOADED    ",m),'Run Test Error');
            end
        end
        
        function result = run_torsion_test(comp)
            try
                KILL_SWITCH_SIGNAL = 86;
                kill_switch_pressed = evalin("base", "kill_switch_pressed");
                assignin('base', "current_test", "torsion");
                if ~kill_switch_pressed
                    pass = requirment_check(comp, true);
                    if ~pass
                        return;
                    end
    
                    disp("torsion");
                    ski_inclinometer_serial = evalin("base","ski_inclinometer_serial");
                    level_inclinometer_serial = evalin("base","level_inclinometer_serial");
                    arduino_serial  = evalin("base", "arduino_serial");
                    force_gauge_left_serial = evalin("base", "force_gauge_left_serial");
                    force_gauge_right_serial = evalin("base", "force_gauge_right_serial");
                    
                    distance_from_start = get_distance_from_start(comp, arduino_serial);
                    if distance_from_start ~= 0
                        return_to_start(comp, arduino_serial);
                    end
                    disp("at start")
                    [force_left, force_right]=force_average(comp, force_gauge_left_serial, force_gauge_right_serial, 1);
                    desired_force = 25;
                    precision = 0.3;
                    step_size_force = 20;
                    if force_left < desired_force-precision || force_right < desired_force-precision
                        move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, desired_force, step_size_force,"up", precision);
                    end
                    %pause(1); 
                    disp("at desired force")
                    [pitch, roll] = get_HWT905TTL_data(comp, level_inclinometer_serial);
                    pitch = pitch * -1;
                    assignin("base", "current_pitch", pitch);
                    assignin("base", "current_roll", roll);
                    disp(strcat("roll: ", num2str(roll)));
                    disp(strcat("pitch: ", num2str(pitch)));
                    desired_angle = 0;
                    precision = 0.15;
                    step_size_level = 15;
                    if ~(pitch < desired_angle+precision && pitch> desired_angle-precision)
                       ret_sig = level_force_gauges(comp, arduino_serial, desired_angle, precision, level_inclinometer_serial, step_size_level);
                    end
                    disp("at desired level");
                    sig = move_force_gauges(comp, arduino_serial, 0, -20);
                    disp("at desired displacement")
                    test_interval_mm = evalin("base", "test_interval_mm");
                    direction = 0;
                    data_matrix_torsion = sensor_automation(comp,test_interval_mm, direction);
                    kill_switch_pressed = evalin("base", "kill_switch_pressed");
                    pause(2);
                   
                    if (kill_switch_pressed == 0)
                        data_matrix_unloaded = evalin("base", "unloaded");
                        temp_save_single_test(comp,data_matrix_torsion, "data_matrix_torsion");

                        [unloaded, torsion] = check_and_adjust_size(comp,data_matrix_unloaded, data_matrix_torsion);
                        assignin("base","torsion",torsion);

                        temp_save_single_test(comp,torsion, "Torsion");

                        [gj_x_points, gj_y_points] = get_GJ_points(comp, unloaded, torsion, test_interval_mm);
                        temp_save_plot_and_points(comp, gj_x_points, gj_y_points, 'GJ');
                        assignin("base", "gj_x_points", gj_x_points);
                        assignin("base", "gj_y_points", gj_y_points);
                        graph_gj_on_ui(comp, gj_x_points, gj_y_points);

                        gj_theta = evalin("base", "gj_dtheta");
                        gj_moment = evalin("base","gj_moment");
                        temp_save_data(comp, gj_theta, 'gj_theta');
                        temp_save_data(comp, gj_moment, 'gj_moment');

                        % move the force gauges to to 0 lbs
                        sig = move_force_gauges(comp, arduino_serial, 0, 20);
                        disp("removed displasment");
                        move_force_gauges_until_desired_force(comp, arduino_serial, force_gauge_left_serial, force_gauge_right_serial, 0, 15,"down", .3);
                        disp("moved to the force gauges to 0");
                        sig = return_to_start(comp, arduino_serial);
                        pause(2);
                        set_current_position(comp, 0);
                    end
                    disp("back at start");
                else
                    sig = 86;
                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   TROSION    ",m),'Run Test Error');
            end
            
        end

        function result = run_ei_test(comp)
            assignin('base', "current_graph_test", "ei");
            ise_unloaded = evalin( 'base', '(exist(''unloaded'',''var'') == 1 && ~isempty(unloaded))');
            if ise_unloaded
                disp("running just loaded");
                run_loaded_test(comp);
            else
                disp("running both unloaded and loaded");
                run_unloaded_test(comp);
                run_loaded_test(comp);
            end
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if kill_switch_pressed
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                %might want to block/disabe averuthing until the kill
                %switch option is slected
                disp("waiting for waitfor");
                waitfor(comp.KillSwitchPressedGo,"Enable", "off"); %edited might not be the right property.
                arduino_serial  = evalin("base", "arduino_serial");
                disp("past waitfor");
                % KillSwitchPressed;
                % uiwait(KillSwitchPressed);
            end
            restart_after_kill = evalin('base', 'restart_after_kill');
            if restart_after_kill
                disp("restarting test");
                assignin('base', "restart_after_kill", false);
                ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
                run_ei_test(comp);
            end
        end

        function result = run_gj_test(comp)
            assignin('base', "current_graph_test", "gj");
            ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
            if ise_unloaded
                run_loaded_test(comp);
            else
                run_unloaded_test(comp);
                run_torsion_test(comp);
            end
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if kill_switch_pressed
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                waitfor(comp.KillSwitchPressedGo,"Enable", "off");
            end
            restart_after_kill = evalin('base', 'restart_after_kill');
            if restart_after_kill
                arduino_serial  = evalin("base", "arduino_serial");
                serial_string = strcat(num2str(17),",0,0");
                ret_sig = serial_communication(comp, arduino_serial, serial_string);
                assignin('base', "restart_after_kill", false);
                run_gj_test(comp,arduino_serial);
            end
        end
 
        function result = run_full_test(comp)
            assignin('base', "current_graph_test", "full");
            ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
            ise_loaded = evalin( 'base', 'exist(''loaded'',''var'') == 1' );
            ise_torsion = evalin( 'base', 'exist(''torsion'',''var'') == 1' );
            if ise_unloaded && ~ise_loaded && ~ise_torsion
                run_loaded_test(comp);
                disp("loaded finished");
                run_torsion_test(comp);
                disp("torsion finished");
            elseif ise_unloaded && ise_loaded && ~ise_torsion
                run_torsion_test(comp);
                disp("torsion finished");
            else
                run_unloaded_test(comp);
                disp("unloaded finished");
                run_loaded_test(comp);
                disp("loaded finished");
                run_torsion_test(comp);
                disp("torsion finished");
            end
            kill_switch_pressed = evalin("base", "kill_switch_pressed");
            if kill_switch_pressed
                comp.KillSwitchPressedDropDown.Enable = "on";
                comp.KillSwitchPressedGo.Enable = "on";
                waitfor(comp.KillSwitchPressedGo,"Enable", "off");
            end
            restart_after_kill = evalin('base', 'restart_after_kill');
            if restart_after_kill
                assignin('base', "restart_after_kill", false);
                run_full_test(comp);
            end
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% GRAPHING DATA ON UI %%%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function result = graph_ei_on_ui(comp, x, y)
            % fig = uifigure;
            % ax = uiaxes(fig);
            % x = linspace(-pi,pi,50);
            % y = 5*sin(x);
            % plot(ax,x,y)

            bar(comp.EITestAxis,y);
            hold(comp.EITestAxis);
            plot(comp.EITestAxis,x,y);

            % comp.EITestAxis.XTick = linspace(Min(x),Max(x),10);
            % comp.EITestAxis.YTick = linspace(Min(y),Max(y),10);
            % plot(comp.EITestAxis, x, y);
        end

        function result = graph_gj_on_ui(comp, x, y)
            
            bar(comp.GJTestAxis,y);
            hold(comp.GJTestAxis);
            plot(comp.GJTestAxis,x,y);

            % comp.GJTestAxis.XTick = linspace(Min(x),Max(x),10);
            % comp.GJTestAxis.YTick = linspace(Min(y),Max(y),10);
            % plot(comp.GJTestAxis, x, y);
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% CHECK FOR REQUIRMENTS %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function pass = requirment_check(comp, intermediate_test)
            ise_test_interval = evalin( 'base', 'exist(''test_interval_mm'',''var'') == 1' );
            ise_unloaded = evalin( 'base', 'exist(''unloaded'',''var'') == 1' );
            ise_dir_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
            message = "";
            pass = true;
            if ~ise_dir_name
                message = "Please set dir name before running tests";
                pass = false;
            elseif ~ise_test_interval
               message = "Please set the test interval before running unloaded test.";
               pass  = false;
            elseif intermediate_test && ~ise_unloaded
               message = "Please run unloaded test before a loaded and torsion test.";
               pass  = false;         
            end
            if ~pass
               fig = uifigure;
               uialert(fig,message,'Test Error');
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            try
                comp.KillSwitchPressedDropDown.Enable = "off";
                comp.KillSwitchPressedDropDown.Visible = "off";
                comp.KillSwitchPressedGo.Enable = "off";
                comp.KillSwitchPressedGo.Visible = "off";
                comp.EditField.Visible = "off";
                comp.EditField.Enable = "off";
                comp.SetNumberofTestsButton.Visible = "off";
                comp.SetNumberofTestsButton.Enable = "off";
                assignin("base", "test_saved", false);
                assignin('base', 'restart_after_kill', false);
                assignin('base', 'current_test', "none");
                ise_test_name = evalin( 'base', 'exist(''dir_name'',''var'') == 1' );
                if ise_test_name
                    test_name = evalin("base", "dir_name");
                    comp.TestNameLabel.Text = test_name;
                else
                    comp.TestNameLabel = "";
                end
            catch e
               m = getReport(e,"extended");
               fig = uifigure;
               uialert(fig,strcat("   START UP    ",m),'Run Test Error');
            end
        end

        % Button pushed function: RunSelectedTestButton
        function RunSelectedTestButtonPushed(comp, event)
            assignin("base", "kill_switch_pressed", false);
            assignin("base", "quit_and_clear", false);
            comp.KillSwitchPressedDropDown.Enable = "off";
            comp.KillSwitchPressedDropDown.Visible = "off";
            comp.KillSwitchPressedGo.Enable = "off";
            comp.KillSwitchPressedGo.Visible = "off";
            comp.RunSelectedTestButton.Enable = "off";
            selected_test = convertCharsToStrings(comp.SelectTesttoRunDropDown.Value);
            disp(selected_test);
            switch selected_test
                case "EI Test"
                    run_ei_test(comp);
                case "GJ Test"
                    run_gj_test(comp);
                % case "Torsion Test"
                %     run_torsion_test(comp);
                case "Full Test"
                    run_full_test(comp);
                case "Run Multiple Tests"
                    ise_num_of_tests = evalin( 'base', 'exist(''num_of_tests'',''var'') == 1' );
                    if ise_num_of_tests
                        num_of_tests = evalin('base', "num_of_tests");
                        restart_after_kill = evalin("base", "restart_after_kill");
                        while num_of_tests > 0 && restart_after_kill == 0;
                            run_full_test(comp);
                            dir_name = evalin('base', "dir_name");
                            restart_after_kill = evalin('base', "restart_after_kill");
                            quit_and_clear = evalin('base', 'quit_and_clear');
                            if quit_and_clear == true
                                break;
                            end
                            save_data_clear_temp(comp, dir_name);
                            num_of_tests = num_of_tests -1;
                        end
                    else
                        str = "Please enter the number of tests you want to run";
                        fig = uifigure;
                        uialert(fig,str,'Test Error');
                    end

                otherwise
                   str = "Test not selected. Please select test.";
                   fig = uifigure;
                   uialert(fig,str,'Test Error');
            end
            comp.RunSelectedTestButton.Enable = "on";
        end

        % Button pushed function: SaveTestButton
        function SaveTestButtonPushed(comp, event)
            directory_name = evalin('base', 'dir_name');
            save_data_clear_temp(comp, directory_name);
            assignin("base", "test_saved", true);
            str = "The current test has been saved";
            fig = uifigure;
            uialert(fig,str,'Success Message','Icon','success');
            %if force gauges not at 0
                %then move to zero
                %move_force_gauges_until_desired_force(ardiuno_serial, force_gage1_serial, force_gage2_serial, 0, 5);
            %if inclinometer is not at start 
                %then move to start
        end

        % Callback function
        function RestartTestButtonPushed(comp, event)
            assignin('base', 'restart_after_kill', true);
        end

        % Callback function
        function QuitandClearTestButtonPushed(comp, event)
            assignin('base', 'restart_after_kill', false);
            evalin('base','clear unloaded loaded torsion');
        end

        % Value changed function: KillSwitchPressedDropDown
        function KillSwitchPressedDropDownValueChanged(comp, event)

        end

        % Button pushed function: KillSwitchPressedGo
        function KillSwitchPressedGoButtonPushed(comp, event)
            value = comp.KillSwitchPressedDropDown.Value;
            arduino_serial = evalin('base', 'arduino_serial');
            switch value
                case "Continue Test"
                    assignin('base', 'restart_after_kill', true);
                case "Restart Test"
                    disp("setting restart after kill to true");
                    current_graph_test = evalin('base', "current_graph_test");
                    if current_graph_test == "ei"
                        evalin('base', strcat("clear ", "unloaded loaded"));
                    end
                    if current_graph_test == "gj"
                        evalin('base', strcat("clear ", "torsion loaded"));
                    end
                    if current_graph_test == "full"
                        evalin('base', 'clear unloaded loaded torsion');
                    end
                    assignin('base', 'restart_after_kill', true);
                case "Quit and Clear Test"
                    disp("trying to clear all saved tests");
                    assignin('base', 'restart_after_kill', false);
                    assignin('base', 'quit_and_clear', true);
                    evalin('base', 'clear unloaded loaded torsion');
                otherwise
                    str = "Test not selected. Please select test.";
                    fig = uifigure;
                    uialert(fig,str,'Test Error');
            end
            assignin('base', 'kill_switch_pressed', false);
            comp.KillSwitchPressedDropDown.Enable = "off";
            comp.KillSwitchPressedDropDown.Visible = "off";
            comp.KillSwitchPressedGo.Enable = "off";
            comp.KillSwitchPressedGo.Visible = "off";
        end

        % Button pushed function: clearDataButton
        function clearDataButtonPushed(comp, event)
            evalin("base", "clear unloaded loaded torsion");
        end

        % Value changed function: SelectTesttoRunDropDown
        function SelectTesttoRunDropDownValueChanged(comp, event)
            value = comp.SelectTesttoRunDropDown.Value;
            if value == "Run Multiple Tests"
                comp.EditField.Visible = "on";
                comp.EditField.Enable = "on";
                comp.SetNumberofTestsButton.Visible = "on";
                comp.SetNumberofTestsButton.Enable = "on";
            end
        end

        % Button pushed function: SetNumberofTestsButton
        function SetNumberofTestsButtonPushed(comp, event)
            num_of_tests = comp.EditField.Value;
            if num2str(num_of_tests) ~= "0"
                assignin('base', "num_of_tests", num_of_tests);
            else
                str = "Please type the number of tests you want to run, Cannot be 0";
                fig = uifigure;
                uialert(fig,str,'Test Error');
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 409 722];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestingPanel = uipanel(comp);
            comp.TestingPanel.Title = 'Testing Panel';
            comp.EITestAxis = uiaxes(comp.TestingPanel);
            title(comp.EITestAxis, 'EI Test')
            xlabel(comp.EITestAxis, 'Milimeters')
            ylabel(comp.EITestAxis, 'N*(m^2)')
            zlabel(comp.EITestAxis, 'Z')
            comp.GJTestAxis = uiaxes(comp.TestingPanel);
            title(comp.GJTestAxis, 'GJ Test')
            xlabel(comp.GJTestAxis, 'Milimeters')
            ylabel(comp.GJTestAxis, 'N*(m^2)')
            zlabel(comp.GJTestAxis, 'Z')
            comp.SelectTesttoRunDropDownLabel = uilabel(comp.TestingPanel);
            comp.SelectTesttoRunDropDownLabel.Text = 'Select Test to Run';
            comp.SelectTesttoRunDropDown = uidropdown(comp.TestingPanel);
            comp.SelectTesttoRunDropDown.Items = {'Options', 'EI Test', 'GJ Test', 'Full Test', 'Run Multiple Tests'};
            comp.SelectTesttoRunDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SelectTesttoRunDropDownValueChanged, true);
            comp.SelectTesttoRunDropDown.Placeholder = 'Options';
            comp.SelectTesttoRunDropDown.Value = 'Options';
            comp.RunSelectedTestButton = uibutton(comp.TestingPanel, 'push');
            comp.RunSelectedTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunSelectedTestButtonPushed, true);
            comp.RunSelectedTestButton.Text = 'Run Selected Test';
            comp.SaveTestButton = uibutton(comp.TestingPanel, 'push');
            comp.SaveTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveTestButtonPushed, true);
            comp.SaveTestButton.Text = 'Save Test';
            comp.TestNameLabel = uilabel(comp.TestingPanel);
            comp.TestNameLabel.Text = 'Test Name';
            comp.KillswitchpressedSelecthowtoproceedDropDownLabel = uilabel(comp.TestingPanel);
            comp.KillswitchpressedSelecthowtoproceedDropDownLabel.Text = 'Kill switch pressed. Select how to proceed. ';
            comp.KillSwitchPressedDropDown = uidropdown(comp.TestingPanel);
            comp.KillSwitchPressedDropDown.Items = {'Options', 'Restart Test', 'Quit and Clear Test', 'Continue Test'};
            comp.KillSwitchPressedDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @KillSwitchPressedDropDownValueChanged, true);
            comp.KillSwitchPressedDropDown.Value = 'Options';
            comp.KillSwitchPressedGo = uibutton(comp.TestingPanel, 'push');
            comp.KillSwitchPressedGo.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @KillSwitchPressedGoButtonPushed, true);
            comp.KillSwitchPressedGo.Text = 'Go';
            comp.clearDataButton = uibutton(comp.TestingPanel, 'push');
            comp.clearDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @clearDataButtonPushed, true);
            comp.clearDataButton.Text = 'Clear For Next Test';
            comp.SetNumberofTestsButton = uibutton(comp.TestingPanel, 'push');
            comp.SetNumberofTestsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SetNumberofTestsButtonPushed, true);
            comp.SetNumberofTestsButton.Text = 'Set Number of Tests';
            comp.EditField = uieditfield(comp.TestingPanel, 'numeric');
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end